classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GenerateButton                matlab.ui.control.Button
        HasilEditField                matlab.ui.control.EditField
        HasilEditFieldLabel           matlab.ui.control.Label
        MasukanPanjangDeretEditField  matlab.ui.control.NumericEditField
        MasukanPanjangDeretEditFieldLabel  matlab.ui.control.Label
        MasukanAngkaYEditField        matlab.ui.control.NumericEditField
        MasukanAngkaYEditFieldLabel   matlab.ui.control.Label
        FibonacciSequenceLabel        matlab.ui.control.Label
        MasukanAngkaXEditField        matlab.ui.control.NumericEditField
        MasukanAngkaXEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % 1. Nilai dari Edit Field
    X = app.MasukanAngkaXEditField.Value;
    Y = app.MasukanAngkaYEditField.Value;
    L = app.MasukanPanjangDeretEditField.Value;

    % 2. Validasi input dari User
    if L < 1
        app.HasilEditField.Value = 'Panjang deret harus lebih dari 0.';
        return; 
    end

    % 3. Perhtungan Deret Fibonacci
    if L == 1
        fibo = [X];
    elseif L == 2
        fibo = [X, Y];
    else
        fibo = [X, Y];
        for i = 3:L
            bilangan_berikutnya = fibo(i-1) + fibo(i-2);
            fibo = [fibo, bilangan_berikutnya];
        end
    end
    
    % 4. Print OUt
    app.HasilEditField.Value = mat2str(fibo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MasukanAngkaXEditFieldLabel = uilabel(app.UIFigure);
            app.MasukanAngkaXEditFieldLabel.Text = 'Masukan Angka X';
            app.MasukanAngkaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.FibonacciSequenceLabel = uilabel(app.UIFigure);
            app.FibonacciSequenceLabel.Text = 'Fibonacci Sequence';
            app.MasukanAngkaYEditFieldLabel = uilabel(app.UIFigure);
            app.MasukanAngkaYEditFieldLabel.Text = 'Masukan Angka Y';
            app.MasukanAngkaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasukanPanjangDeretEditFieldLabel = uilabel(app.UIFigure);
            app.MasukanPanjangDeretEditFieldLabel.Text = 'Masukan Panjang Deret';
            app.MasukanPanjangDeretEditField = uieditfield(app.UIFigure, 'numeric');
            app.HasilEditFieldLabel = uilabel(app.UIFigure);
            app.HasilEditFieldLabel.Text = 'Hasil';
            app.HasilEditField = uieditfield(app.UIFigure, 'text');
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end